classdef AppDT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Home                           matlab.ui.container.Tab
        RunLiveSimulationButton        matlab.ui.control.Button
        RunDataGenerateInsightsButton  matlab.ui.control.Button
        LiveRun                        matlab.ui.container.Tab
        WatchPanel                     matlab.ui.container.Panel
        DoneButton                     matlab.ui.control.Button
        CloseButton                    matlab.ui.control.Button
        Take25gcarbsLabel              matlab.ui.control.Label
        FUELNOWLabel                   matlab.ui.control.Label
        PostExcercise                  matlab.ui.container.Tab
        ActionBox                      matlab.ui.control.TextArea
        AdviceColor                    matlab.ui.control.Lamp
        FuelLabel                      matlab.ui.control.Label
        UIAxes_PR                      matlab.ui.control.UIAxes
        PastComparison                 matlab.ui.container.Tab
        UIAxes_COM                     matlab.ui.control.UIAxes
        FuturePrediction               matlab.ui.container.Tab
        AdviceTextArea                 matlab.ui.control.TextArea
        PREDLabel                      matlab.ui.control.Label
        UIAxes_PRED                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
    RunTimer          % timer object for simulation
    SimData           % for data
    CurrentRow = 1    % row 
    AlertActive = false % sensitivity
    end
    
    methods (Access = private)
function updateSimulatie(app)
    if app.CurrentRow <= height(app.SimData)
        
        % current glucose
        C_glucose = app.SimData.glucose_level(app.CurrentRow);
        
        % show command window
        fprintf('Row %d: Glucose is %.0f\n', app.CurrentRow, C_glucose);

        % check with threshold 70 mg/dl (changeable) for 10 rows
        if app.CurrentRow > 10
            last_15_rows = app.SimData.glucose_level(app.CurrentRow-9 : app.CurrentRow);
     
            if mean(last_15_rows) < 100 && ~app.AlertActive
                app.WatchPanel.Visible = 'on';
                app.AlertActive = true;        
                beep;
                stop(app.RunTimer);            % Pause it
            end
        end
        
        app.CurrentRow = app.CurrentRow + 1;
    else
        stop(app.RunTimer);
    end
end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: RunDataGenerateInsightsButton
        function RunDataGenerateInsightsButtonPushed(app, event)
            % 1. load data
data = readtable('Digital_Twin_Stats_Export4.csv');
app.TabGroup.SelectedTab = app.PostExcercise;

%% post excercise
% 1. Left axis
yyaxis(app.UIAxes_PR, 'left');
plot(app.UIAxes_PR, data.distance, data.glucose_level, 'b-', 'LineWidth', 2);
xlabel(app.UIAxes_PR, 'Distance (km)');
ylabel(app.UIAxes_PR, 'Glucose Level (mg/dL)');

% 2. right axis
yyaxis(app.UIAxes_PR, 'right');
plot(app.UIAxes_PR, data.distance, data.pace, 'r', 'LineWidth',2);
xlabel(app.UIAxes_PR, 'Distance (km)');
ylabel(app.UIAxes_PR, 'Pace (min/km)');
set(app.UIAxes_PR, 'YDir', 'reverse');

% 3. determine the trend
glucose_trend = data.trend_glucose_level;
C_pace = data.pace(end);

if C_pace < 5.81 & glucose_trend < -0.5
    
else
    app.AdviceColor.Color = [0 0.6 0]; % green
    app.ActionBox.Value = 'Between distance 0.8 and 1.3 km you had a steady pace. Took first fuel after this phase en recovered glucose and increased pace in -0,04 min/km';
end

%% past comparison
% 1. Left axis
yyaxis(app.UIAxes_COM, 'left');
plot(app.UIAxes_COM, data.distance, data.pace, 'b', 'LineWidth', 2);
xlabel(app.UIAxes_COM, 'Distance (km)');
ylabel(app.UIAxes_COM, 'Pace (min/km)');
set(app.UIAxes_COM, 'YDir', 'reverse');

% 2. Right axis
yyaxis(app.UIAxes_COM, 'right');
plot(app.UIAxes_COM, data.distance, data.P_pace, 'r', 'LineWidth',2);
xlabel(app.UIAxes_COM, 'Distance (km)');
ylabel(app.UIAxes_COM, 'Pace (min/km)');
set(app.UIAxes_COM, 'YDir', 'reverse');

%% future Prediction
% 1. stimulation of prediction line
predicted_line = data.glucose_level + (data.future_trend_glucose_level * 2); % 2 min in future

plot(app.UIAxes_PRED, data.distance, data.glucose_level, 'b', 'LineWidth', 1.5);
hold(app.UIAxes_PRED, 'on');
plot(app.UIAxes_PRED, data.distance, predicted_line, 'r-', 'LineWidth', 1.5);
hold(app.UIAxes_PRED, 'off');

title(app.UIAxes_PRED, 'Glucose Prediction');
legend(app.UIAxes_PRED, 'Current', 'Predicted (Digital Twin)', 'Location', 'southwest');
ylabel(app.UIAxes_PRED, 'Glucose (mg/dL)');
xlabel(app.UIAxes_PRED, 'Distance (km)');

C_glucose = data.glucose_level(end);
TOTAL_SESSION_TIME=data.time_min(end);
rest_time = max(0, TOTAL_SESSION_TIME - data.time_min(end));
prediction_value = C_glucose + (data.future_trend_glucose_level(end) * rest_time);

% 2. advice 
glucose_threshold_low = 100;
if prediction_value < glucose_threshold_low
    app.PREDLabel.Text = 'DEOPTIMAL'; 
    app.PREDLabel.FontColor = [1 0.4 0]; %orange
    
    % calculate shortage
    shortage = glucose_threshold_low - prediction_value;
    
    app.AdviceTextArea.Value = sprintf(['DT Simulation: If you keep your goal pace in the next activity, ' ...
        'the predicted final value will be %.0f mg/dL. This is to low. \n\n' ...
        'Advice: Take %.0f gram extra carbs with you and divide this over the intensive pieces to avoid a dip.'], ...
        prediction_value, shortage);
else
    app.PREDLabel.Text = 'OPTIMAAL';
    app.PREDLabel.FontColor = [0.2 0.8 0.2]; %green
    
    app.AdviceTextArea.Value = sprintf(['DT Simulation: With an acceleration in the next activity' ...
        'the glucose level expected to remain stable (expected final level: %.0f mg/dL). \n\n' ...
        'Advice: Do not change your fuel strategy.'], ...
        prediction_value);
end

        end

        % Button pushed function: RunLiveSimulationButton
        function RunLiveSimulationButtonPushed(app, event)

    app.TabGroup.SelectedTab = app.LiveRun;

    % 1. load data
    app.SimData = readtable('Digital_Twin_Final_swapped1.csv');
    
    % 2. reset 
    app.CurrentRow = 1;
    app.AlertActive = false;
    app.WatchPanel.Visible = 'off'; 

    % 3. start timer
    app.RunTimer = timer('ExecutionMode', 'fixedRate', ...
                         'Period', 0.1, ... 
                         'TimerFcn', @(~,~) app.updateSimulatie());
    start(app.RunTimer);

        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)

    app.WatchPanel.Visible = 'off'; % not visible
    app.AlertActive = false; % reset
    start(app.RunTimer); % start 
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)

    app.WatchPanel.Visible = 'off'; % not visible
    start(app.RunTimer); % start
    app.CurrentRow = app.CurrentRow + 120; % skip for 2 minutes
    app.AlertActive = false; % reset
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Home = uitab(app.TabGroup);
            app.Home.Title = 'Home';
            app.RunDataGenerateInsightsButton = uibutton(app.Home, 'push');
            app.RunDataGenerateInsightsButton.ButtonPushedFcn = createCallbackFcn(app, @RunDataGenerateInsightsButtonPushed, true);
            app.RunDataGenerateInsightsButton.Text = 'Run Data & Generate Insights';
            app.RunLiveSimulationButton = uibutton(app.Home, 'push');
            app.RunLiveSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunLiveSimulationButtonPushed, true);
            app.RunLiveSimulationButton.Text = 'Run Live Simulation';
            app.LiveRun = uitab(app.TabGroup);
            app.LiveRun.Title = 'Live Run';
            app.WatchPanel = uipanel(app.LiveRun);
            app.WatchPanel.Title = 'Watch Panel';
            app.WatchPanel.Visible = 'off';
            app.FUELNOWLabel = uilabel(app.WatchPanel);
            app.FUELNOWLabel.Text = 'FUEL NOW';
            app.Take25gcarbsLabel = uilabel(app.WatchPanel);
            app.Take25gcarbsLabel.Text = 'Take 25g carbs';
            app.CloseButton = uibutton(app.WatchPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.DoneButton = uibutton(app.WatchPanel, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.PostExcercise = uitab(app.TabGroup);
            app.PostExcercise.Title = 'Post Excercise';
            app.UIAxes_PR = uiaxes(app.PostExcercise);
            title(app.UIAxes_PR, 'Pace vs. Fueling Timing')
            xlabel(app.UIAxes_PR, 'X')
            ylabel(app.UIAxes_PR, 'Y')
            zlabel(app.UIAxes_PR, 'Z')
            app.FuelLabel = uilabel(app.PostExcercise);
            app.FuelLabel.Text = 'Fuel';
            app.AdviceColor = uilamp(app.PostExcercise);
            app.ActionBox = uitextarea(app.PostExcercise);
            app.PastComparison = uitab(app.TabGroup);
            app.PastComparison.Title = 'Past Comparison';
            app.UIAxes_COM = uiaxes(app.PastComparison);
            title(app.UIAxes_COM, 'Pace Comparison')
            xlabel(app.UIAxes_COM, 'X')
            ylabel(app.UIAxes_COM, 'Y')
            zlabel(app.UIAxes_COM, 'Z')
            app.FuturePrediction = uitab(app.TabGroup);
            app.FuturePrediction.Title = 'Future Prediction';
            app.UIAxes_PRED = uiaxes(app.FuturePrediction);
            xlabel(app.UIAxes_PRED, 'X')
            ylabel(app.UIAxes_PRED, 'Y')
            zlabel(app.UIAxes_PRED, 'Z')
            app.PREDLabel = uilabel(app.FuturePrediction);
            app.AdviceTextArea = uitextarea(app.FuturePrediction);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppDT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end